classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UsethemousecursortorotatethemapusethescrollLabel  matlab.ui.control.Label
        ControlPanel   matlab.ui.container.Panel
        sendButton     matlab.ui.control.Button
        InputTextArea  matlab.ui.control.TextArea
        ResetButton    matlab.ui.control.Button
        ChatTextArea   matlab.ui.control.TextArea
        TheGlobePanel  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Globe               % handle to geoglobe
        %DeepSeekAPIKey = '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' % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Create the geoglobe in the panel
                app.Globe = geoglobe(app.TheGlobePanel);
                app.Globe.Basemap = 'streets';
            campos(app.Globe, 0, 0, 1e7);
            app.ChatTextArea.Value = "Hello! I'm Globe 😊. Ask me about" + ...
                " Any place eg you can write Boardbazar/Gazipur/Dhaka/Bangladesh." + ...
                " It doesn't have to be only city/country name. " + ...
                "  ⚠️INTERNET CONNECTION IS NEEDED TO RUN THIS PROGRAM⚠️"    ;
            app.InputTextArea.Value = "";
        end


        % Call DeepSeek API
   function reply = callDeepSeek(app, prompt)
    fprintf('Searching for: %s\n', prompt);
    
    try
        % Use system curl command for DuckDuckGo
        cmd = ['curl -s "https://api.duckduckgo.com/?q=' prompt '&format=json&no_html=1"'];
        [status, result] = system(cmd);
        
        if status == 0
            % Parse the JSON response
            response = jsondecode(result);
            
            % Extract information
            if ~isempty(response.Abstract) && ~strcmp(response.Abstract, '')
                reply = response.Abstract;
            elseif ~isempty(response.Answer) && ~strcmp(response.Answer, '')
                reply = response.Answer;
            elseif ~isempty(response.RelatedTopics)
                if isstruct(response.RelatedTopics)
                    reply = response.RelatedTopics(1).Text;
                else
                    reply = response.RelatedTopics{1}.Text;
                end
            elseif ~isempty(response.Definition) && ~strcmp(response.Definition, '')
                reply = response.Definition;
            else
                reply = 'Information found but no clear summary available.';
            end
            
            % Clean up the response
            reply = regexprep(reply, '<[^>]*>', '');
            
            % NOW GET COORDINATES using curl for OpenStreetMap
            try
                geoCmd = ['curl -s "https://nominatim.openstreetmap.org/search?q=' prompt '&format=json&limit=1"'];
                [geoStatus, geoResult] = system(geoCmd);
                
                if geoStatus == 0 && ~isempty(geoResult) && length(geoResult) > 2
                    geoResponse = jsondecode(geoResult);
                    
                    if ~isempty(geoResponse) && isstruct(geoResponse) && isfield(geoResponse, 'lat')
                        lat = str2double(geoResponse(1).lat);
                        lon = str2double(geoResponse(1).lon);
                        
                        fprintf('Found coordinates: %.4f, %.4f\n', lat, lon);
                        
                        % ROTATE THE GLOBE to these coordinates
                        campos(app.Globe, lat, lon, 100000); % 1000km altitude
                        
                        reply = sprintf('%s\n\n📍 Coordinates: %.4f, %.4f\n🌍 Globe rotated to location!', reply, lat, lon);
                    end
                end
            catch geoError
                fprintf('Geocoding failed: %s\n', geoError.message);
                % Continue without coordinates - at least we have DuckDuckGo info
            end
            
        else
            reply = 'Error: Could not fetch information.';
        end
        
    catch ME
        fprintf('DuckDuckGo API Error: %s\n', ME.message);
        reply = 'Cannot access information at the moment.';
    end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             campos(app.Globe, 0, 0, 1e7);
             app.ChatTextArea.Value = "Hello! I'm Globe 😊. Ask me about" + ...
                " Any place eg you can write Boardbazar/Gazipur/Dhaka/Bangladesh." + ...
                " It doesn't have to be only city/country name. " + ...
                "  ⚠️INTERNET CONNECTION IS NEEDED TO RUN THIS PROGRAM⚠️"    ;
        end

        % Button pushed function: sendButton
        function sendButtonPushed(app, event)
        userInput = app.InputTextArea.Value;
    
    % Ensure userInput is a char
    if isstring(userInput)
        userInput = char(userInput);
    elseif iscell(userInput)
        userInput = strjoin(userInput, ' ');
    end

    % Get current chat
    chat = app.ChatTextArea.Value;

    % Ensure chat is a cell array of char
    if isstring(chat)
        chat = cellstr(chat);
    elseif ischar(chat)
        chat = {chat};
    end

    % Append user's message
    chat{end+1} = ['You: ' userInput];

      % Update chat display immediately
    app.ChatTextArea.Value = chat;
    drawnow; % Force UI update
    
    % CLEAR THE INPUT AREA
    app.InputTextArea.Value = '';

    % Call DeepSeek
    reply = app.callDeepSeek(userInput);

    % Ensure reply is char
    if isstring(reply)
        reply = char(reply);
    elseif iscell(reply)
        reply = strjoin(reply, ' ');
    end

        % Get updated chat history (in case it changed during API call)
    chat= app.ChatTextArea.Value;
    if isstring(chat)
        chat = cellstr(chat);
    elseif ischar(chat)
        chat = {chat};
    end
    
    % Append API reply to chat history
    chat{end+1} = ['Globe: ' reply];
    
    % Update chat display
    app.ChatTextArea.Value = chat;
    
    % Auto-scroll to bottom
    app.ChatTextArea.scroll('bottom');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TheGlobePanel = uipanel(app.UIFigure);
            app.TheGlobePanel.Title = 'The Globe 🌍';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.ChatTextArea = uitextarea(app.ControlPanel);
            app.ResetButton = uibutton(app.ControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset 🔄';
            app.InputTextArea = uitextarea(app.ControlPanel);
            app.InputTextArea.Placeholder = 'Write your location name here.....';
            app.sendButton = uibutton(app.ControlPanel, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButtonPushed, true);
            app.sendButton.Text = 'send 🚀';
            app.UsethemousecursortorotatethemapusethescrollLabel = uilabel(app.UIFigure);
            app.UsethemousecursortorotatethemapusethescrollLabel.Text = '💡  Use the mouse cursor to rotate the GLOBE, use the scrollpad to Zoom In or Zoom Out';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end